classdef testinterface2Copie < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Image10                matlab.ui.control.Image
        BackToMenuButton       matlab.ui.control.Button
        UploadYourImageButton  matlab.ui.control.Button
        Image9                 matlab.ui.control.Image
        Image8                 matlab.ui.control.Image
        Image7                 matlab.ui.control.Image
        Image6                 matlab.ui.control.Image
        Mosaic7Button          matlab.ui.control.Button
        Mosaic6Button          matlab.ui.control.Button
        Mosaic5Button          matlab.ui.control.Button
        Mosaic4Button          matlab.ui.control.Button
        Mosaic3Button_2        matlab.ui.control.Button
        Mosaic2Button_2        matlab.ui.control.Button
        DegRotationButton_6    matlab.ui.control.Button
        DegRotationButton_5    matlab.ui.control.Button
        DegRotationButton_4    matlab.ui.control.Button
        GrayScaleButton_2      matlab.ui.control.Button
        BinaryButton_2         matlab.ui.control.Button
        Mosaic1Button          matlab.ui.control.Button
        Image3                 matlab.ui.control.Image
        Image2                 matlab.ui.control.Image
        UIAxes                 matlab.ui.control.UIAxes
        ContextMenu            matlab.ui.container.ContextMenu
        Menu                   matlab.ui.container.Menu
        Menu2                  matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function UploadYourImageButtonPushed(app, event)
            global a;
            [filename, pathname] = uigetfile('*.*', 'Pick an Image');
            filename=strcat(pathname,filename);
            a=imread(filename);
            imresize(a,[512 NaN]);
            imshow(a,'Parent',app.UIAxes);
        end

        % Callback function
        function DegRotationButtonPushed(app, event)
            global a;
            rot1=imrotate(a,90);
            %figure
            imshow(rot1);
            title("90 degrees rotation")
        end

        % Callback function
        function GrayScaleButtonPushed(app, event)
            global a;
            GS= rgb2gray(a);
            %figure
            imshow(GS);
        end

        % Callback function
        function MosaicButtonPushed(app, event)
            global a;
            
            global aa;
            
            %MOSS = a;
            %MOSS = rgb2gray(MOSS);
            
            %III = load('E:\modules\mosa7.jpg');
            III = which('E:\modules\mosa7.jpg');
            aa=imresize(a,[1024 NaN]);
            %I=imresize(III,[1024 NaN]);
            imshow(aa,'Parent',app.UIAxes2);
            hold on;
            h = imagesc(III);
            set(h,'AlphaData',.3);
            
        end

        % Callback function
        function RotationDropDownValueChanged(app, event)
            value = app.RotationDropDown.Value;
            global a;
            global rot1;
            rot1 = a;
            rot1 = imrotate(rot1,90);
            imshow(rot1,'Parent',app.UIAxes2);
        end

        % Callback function
        function BinaryButtonPushed(app, event)
            global a;
            BW = im2bw(a,0.5);
            %figure
            imshow(BW);
        end

        % Callback function
        function BackToMenuButtonPushed(app, event)
            app2Copie();
        end

        % Button pushed function: Mosaic5Button
        function Mosaic5ButtonPushed(app, event)
            global a;
            EE = a;
            II = imread("mosa5.jpg");
            E = imresize(EE, [1024 NaN]);
            I = imresize(II, [1024 NaN]);
            imshow( E );
            hold on;
            h = imagesc( I ); % show the edge image
            set( h, 'AlphaData', .3 ); % .5 transparency
            % title("Mosaic")
        end

        % Button pushed function: Mosaic1Button
        function Mosaic1ButtonPushed(app, event)
            global a;
            EE = a;
            II = imread("mosa1.jpg");
            E = imresize(EE, [1024 NaN]);
            I = imresize(II, [1024 NaN]);
            imshow( E );
            hold on;
            h = imagesc( I ); % show the edge image
            set( h, 'AlphaData', .3 ); % .5 transparency
            % title("Mosaic")
        end

        % Callback function
        function Mosaic2ButtonPushed(app, event)
            global a;
            EE = a;
            II = imread("mosa2.jpg");
            E = imresize(EE, [1024 NaN]);
            I = imresize(II, [1024 NaN]);
            imshow( E );
            hold on;
            h = imagesc( I ); % show the edge image
            set( h, 'AlphaData', .3 ); % .5 transparency
            % title("Mosaic")
        end

        % Callback function
        function Mosaic3ButtonPushed(app, event)
            global a;
            EE = a;
            II = imread("mosa3.jpg");
            E = imresize(EE, [1024 NaN]);
            I = imresize(II, [1024 NaN]);
            imshow( E );
            hold on;
            h = imagesc( I ); % show the edge image
            set( h, 'AlphaData', .3 ); % .5 transparency
            % title("Mosaic")
        end

        % Button pushed function: Mosaic4Button
        function Mosaic4ButtonPushed(app, event)
            global a;
            EE = a;
            II = imread("mosa4.jpg");
            E = imresize(EE, [1024 NaN]);
            I = imresize(II, [1024 NaN]);
            imshow( E );
            hold on;
            h = imagesc( I ); % show the edge image
            set( h, 'AlphaData', .3 ); % .5 transparency
            % title("Mosaic")
        end

        % Button pushed function: Mosaic6Button
        function Mosaic6ButtonPushed(app, event)
            global a;
            EE = a;
            II = imread("mosa6.jpg");
            E = imresize(EE, [1024 NaN]);
            I = imresize(II, [1024 NaN]);
            imshow( E );
            hold on;
            h = imagesc( I ); % show the edge image
            set( h, 'AlphaData', .3 ); % .5 transparency
            % title("Mosaic")
        end

        % Button pushed function: Mosaic7Button
        function Mosaic7ButtonPushed(app, event)
            global a;
            EE = a;
            II = imread("mosa7.jpg");
            E = imresize(EE, [1024 NaN]);
            I = imresize(II, [1024 NaN]);
            imshow( E );
            hold on;
            h = imagesc( I ); % show the edge image
            set( h, 'AlphaData', .3 ); % .5 transparency
            % title("Mosaic")
        end

        % Callback function
        function DegRotationButton_2Pushed(app, event)
            global a;
            rot2=imrotate(a,180);
            %figure
            imshow(rot2);
        end

        % Callback function
        function DegRotationButton_3Pushed(app, event)
            global a;
            rot3=imrotate(a,360);
            %figure
            imshow(rot3);
        end

        % Button pushed function: UploadYourImageButton
        function UploadYourImageButtonPushed2(app, event)
            global a;
            [filename, pathname] = uigetfile('*.*', 'Pick an Image');
            filename=strcat(pathname,filename);
            a=imread(filename);
            imresize(a,[512 NaN]);
            imshow(a,'Parent',app.UIAxes);
        end

        % Button pushed function: BinaryButton_2
        function BinaryButton_2Pushed(app, event)
            global a;
            BW = im2bw(a,0.5);
            %figure
            imshow(BW);
        end

        % Menu selected function: Menu
        function MenuSelected(app, event)
            
        end

        % Button pushed function: GrayScaleButton_2
        function GrayScaleButton_2Pushed(app, event)
            global a;
            GS= rgb2gray(a);
            %figure
            imshow(GS);
        end

        % Button pushed function: DegRotationButton_4
        function DegRotationButton_4Pushed(app, event)
            global a;
            rot1=imrotate(a,90);
            %figure
            imshow(rot1);
            title("90 degrees rotation")
        end

        % Button pushed function: DegRotationButton_5
        function DegRotationButton_5Pushed(app, event)
            global a;
            rot1=imrotate(a,180);
            %figure
            imshow(rot1);
            title("90 degrees rotation")
        end

        % Button pushed function: DegRotationButton_6
        function DegRotationButton_6Pushed(app, event)
            global a;
            rot1=imrotate(a,360);
            %figure
            imshow(rot1);
            title("90 degrees rotation")
        end

        % Button pushed function: Mosaic2Button_2
        function Mosaic2Button_2Pushed(app, event)
            global a;
            EE = a;
            II = imread("mosa2.jpg");
            E = imresize(EE, [1024 NaN]);
            I = imresize(II, [1024 NaN]);
            imshow( E );
            hold on;
            h = imagesc( I ); % show the edge image
            set( h, 'AlphaData', .3 ); % .5 transparency
            % title("Mosaic")
        end

        % Button pushed function: Mosaic3Button_2
        function Mosaic3Button_2Pushed(app, event)
            global a;
            EE = a;
            II = imread("mosa3.jpg");
            E = imresize(EE, [1024 NaN]);
            I = imresize(II, [1024 NaN]);
            imshow( E );
            hold on;
            h = imagesc( I ); % show the edge image
            set( h, 'AlphaData', .3 ); % .5 transparency
            % title("Mosaic")
        end

        % Button pushed function: BackToMenuButton
        function BackToMenuButtonPushed2(app, event)
            app2Copie();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'bg black.jpg';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = 'bg black.jpg';
            app.Mosaic1Button = uibutton(app.UIFigure, 'push');
            app.Mosaic1Button.ButtonPushedFcn = createCallbackFcn(app, @Mosaic1ButtonPushed, true);
            app.Mosaic1Button.Icon = 'mosa1.jpg';
            app.Mosaic1Button.Text = 'Mosaic 1';
            app.BinaryButton_2 = uibutton(app.UIFigure, 'push');
            app.BinaryButton_2.ButtonPushedFcn = createCallbackFcn(app, @BinaryButton_2Pushed, true);
            app.BinaryButton_2.Icon = 'icons8-noir-et-blanc-100.png';
            app.BinaryButton_2.Text = 'Binary';
            app.GrayScaleButton_2 = uibutton(app.UIFigure, 'push');
            app.GrayScaleButton_2.ButtonPushedFcn = createCallbackFcn(app, @GrayScaleButton_2Pushed, true);
            app.GrayScaleButton_2.Icon = 'icons8-niveaux-de-gris-96.png';
            app.GrayScaleButton_2.Text = 'GrayScale';
            app.DegRotationButton_4 = uibutton(app.UIFigure, 'push');
            app.DegRotationButton_4.ButtonPushedFcn = createCallbackFcn(app, @DegRotationButton_4Pushed, true);
            app.DegRotationButton_4.Icon = 'icons8-tourner-la-page-dans-le-sens-des-aiguilles-100.png';
            app.DegRotationButton_4.Text = {'90 Deg.'; 'Rotation'};
            app.DegRotationButton_5 = uibutton(app.UIFigure, 'push');
            app.DegRotationButton_5.ButtonPushedFcn = createCallbackFcn(app, @DegRotationButton_5Pushed, true);
            app.DegRotationButton_5.Icon = 'icons8-tourner-la-page-dans-le-sens-des-aiguilles-100.png';
            app.DegRotationButton_5.Text = {'180 Deg. '; 'Rotation'};
            app.DegRotationButton_6 = uibutton(app.UIFigure, 'push');
            app.DegRotationButton_6.ButtonPushedFcn = createCallbackFcn(app, @DegRotationButton_6Pushed, true);
            app.DegRotationButton_6.Icon = 'icons8-tourner-la-page-dans-le-sens-des-aiguilles-100.png';
            app.DegRotationButton_6.Text = {'360 Deg. '; 'Rotation'};
            app.Mosaic2Button_2 = uibutton(app.UIFigure, 'push');
            app.Mosaic2Button_2.ButtonPushedFcn = createCallbackFcn(app, @Mosaic2Button_2Pushed, true);
            app.Mosaic2Button_2.Icon = 'mosa2.jpg';
            app.Mosaic2Button_2.Text = 'Mosaic 2';
            app.Mosaic3Button_2 = uibutton(app.UIFigure, 'push');
            app.Mosaic3Button_2.ButtonPushedFcn = createCallbackFcn(app, @Mosaic3Button_2Pushed, true);
            app.Mosaic3Button_2.Icon = 'mosa3.jpg';
            app.Mosaic3Button_2.Text = 'Mosaic 3';
            app.Mosaic4Button = uibutton(app.UIFigure, 'push');
            app.Mosaic4Button.ButtonPushedFcn = createCallbackFcn(app, @Mosaic4ButtonPushed, true);
            app.Mosaic4Button.Icon = 'mosa4.jpg';
            app.Mosaic4Button.Text = 'Mosaic 4';
            app.Mosaic5Button = uibutton(app.UIFigure, 'push');
            app.Mosaic5Button.ButtonPushedFcn = createCallbackFcn(app, @Mosaic5ButtonPushed, true);
            app.Mosaic5Button.Icon = 'mosa5.jpg';
            app.Mosaic5Button.Text = 'Mosaic 5';
            app.Mosaic6Button = uibutton(app.UIFigure, 'push');
            app.Mosaic6Button.ButtonPushedFcn = createCallbackFcn(app, @Mosaic6ButtonPushed, true);
            app.Mosaic6Button.Icon = 'mosa6.jpg';
            app.Mosaic6Button.Text = 'Mosaic 6';
            app.Mosaic7Button = uibutton(app.UIFigure, 'push');
            app.Mosaic7Button.ButtonPushedFcn = createCallbackFcn(app, @Mosaic7ButtonPushed, true);
            app.Mosaic7Button.Icon = 'mosa7.jpg';
            app.Mosaic7Button.Text = 'Mosaic 7';
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.ImageSource = 'bg rid1.png';
            app.Image7 = uiimage(app.UIFigure);
            app.Image7.ImageSource = 'bg rid2.png';
            app.Image8 = uiimage(app.UIFigure);
            app.Image8.ImageSource = 'bg rid3.png';
            app.Image9 = uiimage(app.UIFigure);
            app.Image9.ImageSource = 'bg rid.png';
            app.UploadYourImageButton = uibutton(app.UIFigure, 'push');
            app.UploadYourImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadYourImageButtonPushed2, true);
            app.UploadYourImageButton.Icon = 'icons8-ajouter-une-image-96.png';
            app.UploadYourImageButton.Text = 'Upload Your Image';
            app.BackToMenuButton = uibutton(app.UIFigure, 'push');
            app.BackToMenuButton.ButtonPushedFcn = createCallbackFcn(app, @BackToMenuButtonPushed2, true);
            app.BackToMenuButton.Icon = 'icon home.png';
            app.BackToMenuButton.Text = 'Back To Menu';
            app.Image10 = uiimage(app.UIFigure);
            app.Image10.ImageSource = 'goldenblphotolab.jpg';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.MenuSelectedFcn = createCallbackFcn(app, @MenuSelected, true);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.UIAxes.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testinterface2Copie

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end